classdef SpatialPositionMarking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button2            matlab.ui.control.Button
        Button             matlab.ui.control.Button
        indexLabel         matlab.ui.control.Label
        segmentLabel       matlab.ui.control.Label
        nextSegmentButton  matlab.ui.control.Button
        antIDLabel         matlab.ui.control.Label
        undoButton         matlab.ui.control.Button
        saveButton         matlab.ui.control.Button
        axesView           matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Property % Description
        csvFile = '';

        nestType = '';
        nestLocation = '';
        halfWayPoint = 0;

        absoluteIndex = 1;
        videoFrameIndex = 1;
        actionIndex = 1;

        segmentCoordinateLog = struct('xCoordinate', {}, 'yCoordinate', {});
        coordinateLogIndex = 1;
        numberOfSegments = 1;
        numberOfAnts = 1;
        currentSegment = 1;
        currentAnt = 1;
        eachSegmentLength = [];
        antIDs = [];

        maxVideoWidth = 0;
        maxVideoHeight = 0;
        videoFrameCount = 1;
    end

    methods (Access = private)

        function getCSVFile(app, fileComponentVariable)
            app.csvFile = append(fileComponentVariable{1}, '_', fileComponentVariable{2}, '_', fileComponentVariable{3}, '_', fileComponentVariable{4}, '_', fileComponentVariable{5}, '_', fileComponentVariable{6}, '.csv');
        end

        function displayMessages(app, shouldBeDisplayed)
            switch shouldBeDisplayed
                case true
                    uiMessage = "Is the nest round or square?";
                    messageTitle = "Shape of Nest";
                    selection = uiconfirm(app.UIFigure, uiMessage, messageTitle, 'Options', {'Round', 'Square'});
                    switch selection
                        case 'Round'
                            app.nestType = 'R';
                        case 'Square'
                            app.nestType = 'S';
                    end

                    pause(0.5)
                    uiMessage = "Is the nest on the left or right side of the screen?";
                    messageTitle = "Nest Location";
                    selection = uiconfirm(app.UIFigure, uiMessage, messageTitle, 'Options', {'Left', 'Right'});
                    switch selection
                        case 'Left'
                            app.nestLocation = 'L';
                        case 'Right'
                            app.nestLocation = 'R';
                    end

                    pause(0.5)
                    app.axesView.Title.String = "Half Way Point";
                    uiMessage = "Please choose the half way point between the nest and foraging arena";
                    messageTitle = "Half Way Point";
                    selection = uiconfirm(app.UIFigure, uiMessage, messageTitle, 'Options', {'OK'});
                case false
                    return
            end
        end

        function updateLabels(app)
            app.segmentLabel.Text = sprintf('Segment: %d', app.currentSegment);
            app.antIDLabel.Text = append("Ant ID: ", app.antIDs(app.currentAnt, 1:3));
            if app.coordinateLogIndex > app.eachSegmentLength(app.currentSegment)
                if app.currentSegment == app.numberOfSegments
                    if app.currentAnt == app.numberOfAnts
                        app.nextSegmentButton.Text = "Complete Analysis";
                    else
                        app.nextSegmentButton.Text = "Next Ant";
                    end
                else
                    app.nextSegmentButton.Text = "Next Segment";
                end
                app.indexLabel.Text = sprintf('Index: Final');
                app.nextSegmentButton.Enable = "on";
            else
                app.indexLabel.Text = sprintf('Index: %d', app.coordinateLogIndex);
            end
        end

        function saveData(app)
            global matObject

            matObject.savedAbsoluteIndex = app.absoluteIndex;
            matObject.savedVideoFrameIndex = app.videoFrameIndex;
            matObject.savedActionIndex = app.actionIndex;

            matObject.savedCoordinateLog(app.currentAnt, app.currentSegment) = {app.segmentCoordinateLog};
            matObject.savedCoordinateLogIndex = app.coordinateLogIndex;
            matObject.savedCurrentSegment = app.currentSegment;
            matObject.savedCurrentAnt = app.currentAnt;

            app.saveButton.Enable = 'off';
        end

        function updateAntAndSegment(app)
            if app.currentSegment < app.numberOfSegments
                app.currentSegment = app.currentSegment + 1;
                updateLabels(app)
            else
                if app.currentAnt == app.numberOfAnts
                    %END APPLICATION
                else
                    app.currentSegment = 1;
                    app.currentAnt = app.currentAnt + 1;
                    updateLabels(app)
                end
            end
        end

        function updateVideoFrameView(app, frameVar)
            global video
            
            switch frameVar
                case "up"
                    if app.videoFrameIndex == video.NumFrames
                        app.videoFrameIndex = 1;
                    else
                        app.videoFrameIndex = app.videoFrameIndex + 1;
                    end
                case "down"
                    if app.videoFrameIndex > 1
                        app.videoFrameIndex = app.videoFrameIndex - 1;
                    end
                otherwise
                    app.videoFrameIndex = app.videoFrameIndex;
            end

            videoFrame = read(video, app.videoFrameIndex);
            imshow(videoFrame, 'Parent', app.axesView)
        end

        function formatAxesView(app)
            axis(app.axesView, "tight");
            movegui(app.axesView, "center");
            hold(app.axesView, "on");
            app.axesView.PickableParts = "none";
            disableDefaultInteractivity(app.axesView);
        end

        function undoAction(app)
            if app.coordinateLogIndex > 2
                app.coordinateLogIndex = app.coordinateLogIndex - 1;
                app.segmentCoordinateLog(app.coordinateLogIndex) = [];
                app.absoluteIndex = app.absoluteIndex - 1;

                updateVideoFrameView(app, "down")
                plot(app.axesView, (app.segmentCoordinateLog(1, app.coordinateLogIndex - 1).xCoordinate) / (app.maxVideoWidth/1280), (app.segmentCoordinateLog(1, app.coordinateLogIndex - 1).yCoordinate) / (app.maxVideoHeight/720), "-ob","MarkerFaceColor", "b")
                app.saveButton.Enable = "on";
            elseif app.coordinateLogIndex == 2
                app.coordinateLogIndex = app.coordinateLogIndex - 1;
                app.segmentCoordinateLog(app.coordinateLogIndex) = [];
                app.absoluteIndex = app.absoluteIndex - 1;

                updateVideoFrameView(app, "")
                app.saveButton.Enable = "on";
            end
        end
        
        function loadVariables(app)
            global matObject
            
            app.nestType = matObject.savedNestType;
            app.nestLocation = matObject.savedNestLocation;
            app.halfWayPoint = matObject.savedHalfWayPoint;
            
            app.maxVideoWidth = matObject.savedMaxVideoWidth;
            app.maxVideoHeight = matObject.savedMaxVideoHeight;
            app.videoFrameCount = matObject.savedVideoFrameCount;

            app.absoluteIndex = matObject.savedAbsoluteIndex;
            app.actionIndex = matObject.savedActionIndex;
            app.videoFrameIndex = matObject.savedVideoFrameIndex;

            app.currentSegment = matObject.savedCurrentSegment;
            app.currentAnt = matObject.savedCurrentAnt;
            app.segmentCoordinateLog = matObject.savedCoordinateLog(app.currentAnt, app.currentSegment);
            app.segmentCoordinateLog = app.segmentCoordinateLog{1};
            app.coordinateLogIndex = matObject.savedCoordinateLogIndex;
            app.numberOfAnts = matObject.savedNumberOfAnts;
            app.numberOfSegments = matObject.savedNumberOfSegments;
            app.eachSegmentLength = matObject.savedEachSegmentLength;
            app.antIDs = matObject.savedAntIDs;
        end
        
        function displayMissingCSVFileWarning(app)
            uiMessage = "Warning: The CSV file is not in the directory that you selected. Please make sure that the CSV file and video are in the same directory. If you still have trouble after this, please contact Cole or Mariah. The app will now close.";
            messageTitle = "CSV File not found";
            selection = uiconfirm(app.UIFigure, uiMessage, messageTitle, 'Options', {'OK'});
            if selection == selection
                quit
            end
        end
        
        function updateButtons(app)
            if app.coordinateLogIndex > 1
                plot(app.axesView, (app.segmentCoordinateLog(1, app.coordinateLogIndex - 1).xCoordinate) / (app.maxVideoWidth / 1280), (app.segmentCoordinateLog(1, app.coordinateLogIndex - 1).yCoordinate) / (app.maxVideoHeight / 720), "-ob","MarkerFaceColor", "b");
                app.undoButton.Enable = "on";
                if app.coordinateLogIndex > app.eachSegmentLength(app.currentSegment)
                    app.nextSegmentButton.Enable = "on";
                    if app.currentSegment == app.numberOfSegments
                        app.nextSegmentButton.Text = "Next Ant";
                    else
                        app.nextSegmentButton.Text = "Next Segment";
                    end
                else
                    app.nextSegmentButton.Enable = "off";
                end
            else
                app.nextSegmentButton.Enable = "off";
                app.undoButton.Enable = "off";
                app.saveButton.Enable = 'off';
            end
        end
        
        function saveInitializingData(app)
            global matObject
            matObject.savedNestType = app.nestType;
            matObject.savedNestLocation = app.nestLocation;
            matObject.savedHalfWayPoint = app.halfWayPoint;
            matObject.savedNumberOfAnts = app.numberOfAnts;
            matObject.savedNumberOfSegments = app.numberOfSegments;

            matObject.savedMaxVideoWidth = app.maxVideoWidth;
            matObject.savedMaxVideoHeight = app.maxVideoHeight;
            matObject.savedVideoFrameCount = app.videoFrameCount;

            matObject.savedEachSegmentLength = app.eachSegmentLength;
            matObject.savedAntIDs = app.antIDs;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function loadView(app)
            global video
            global matObject
            global csvFileObject
            global filePath

            [videoFile, filePath] = uigetfile('*.mp4');
            if isequal(videoFile, 0)
                disp("Selection canceled");
            else
                csvFileComponents = strsplit(videoFile, '_');
                matFileComponents = strsplit(videoFile, '.');

                getCSVFile(app, csvFileComponents)
                matFileName = append(matFileComponents(1), '.mat');

                video = VideoReader(fullfile(filePath, videoFile));
                app.maxVideoWidth = video.Width;
                app.maxVideoHeight = video.Height;
                app.videoFrameCount = video.NumFrames;
                
                if isfile(fullfile(filePath, app.csvFile))
                    csvFileObject = readtable(fullfile(filePath, app.csvFile));
                else
                    displayMissingCSVFileWarning(app)
                end
                
                matObject = matfile(fullfile(filePath, matFileName{1}), 'Writable', true);

                if isfile(fullfile(filePath, matFileName{1}))
                    loadVariables(app)
                    shouldDisplayMessage = false;
                else
                    shouldDisplayMessage = true;

                    app.numberOfAnts = length(unique(csvFileObject.AntID));
                    app.numberOfSegments = length(1:max(csvFileObject.SegmentNumber));
                    app.eachSegmentLength = sum(csvFileObject.SegmentNumber == (1:8) & csvFileObject.AntID == string(csvFileObject.AntID{1}));
                    app.antIDs = cell2mat(csvFileObject.AntID(1:(sumOfSegments):end));
                end
                
                %How will this affect the CSV file?
                numberOfCellsPerAnt = sum(app.eachSegmentLength, 2);
                if numberOfCellsPerAnt ~= app.videoFrameCount
                    %Format the final index of "eachSegmentLength" so that
                    disp("Error: Frame count is not the same as the number of cells per ant in the CSV file")
                    disp("Attempting to fix...")
                    if numberOfCellsPerAnt > app.videoFrameCount
                        app.eachSegmentLength(app.numberOfSegments) = app.eachSegmentLength(app.numberOfSegments) - (numberOfCellsPerAnt - app.videoFrameCount);
                        disp(sum(app.eachSegmentLength, 2))
                    end
                end

                updateButtons(app)
                formatAxesView(app)
                updateVideoFrameView(app, "")
                if app.coordinateLogIndex > 1
                    plot(app.axesView, (app.segmentCoordinateLog(1, app.coordinateLogIndex - 1).xCoordinate) / (app.maxVideoWidth/1280), (app.segmentCoordinateLog(1, app.coordinateLogIndex - 1).yCoordinate) / (app.maxVideoHeight/720), "-ob","MarkerFaceColor", "b")
                end
                updateLabels(app)
                displayMessages(app, shouldDisplayMessage)
                
                clear csvFileObject
            end
        end

        % Button down function: UIFigure
        function figurePressed(app, event)
            clickedXCoordinate = app.axesView.CurrentPoint(1,1);
            clickedYCoordinate = app.axesView.CurrentPoint(1,2);

            switch app.halfWayPoint
                case 0
                    if clickedXCoordinate >= app.axesView.XLim(1) && clickedXCoordinate <= app.axesView.XLim(2) && clickedYCoordinate >= app.axesView.YLim(1) && clickedYCoordinate <= app.axesView.YLim(2)
                        app.halfWayPoint = clickedXCoordinate;
                        
                        saveInitializingData(app)
                        saveData(app)
                        app.axesView.Title.String = "";
                    end
                otherwise
                    if clickedXCoordinate >= app.axesView.XLim(1) && clickedXCoordinate <= app.axesView.XLim(2) && clickedYCoordinate >= app.axesView.YLim(1) && clickedYCoordinate <= app.axesView.YLim(2) && app.coordinateLogIndex <= app.eachSegmentLength(app.currentSegment)

                        app.segmentCoordinateLog(app.coordinateLogIndex) = struct('xCoordinate', (clickedXCoordinate) / (app.maxVideoWidth/1280), 'yCoordinate', (clickedYCoordinate) / (app.maxVideoHeight/720));
                        app.coordinateLogIndex = app.coordinateLogIndex + 1;

                        updateVideoFrameView(app, "up")

                        app.absoluteIndex = app.absoluteIndex + 1;
                        plot(app.axesView, (clickedXCoordinate) / (app.maxVideoWidth/1280), (clickedYCoordinate) / (app.maxVideoHeight/720), "-ob","MarkerFaceColor", "b");

                        app.saveButton.Enable = 'on';
                        app.undoButton.Enable = "on";
                        updateLabels(app)
                    end
            end
        end

        % Button pushed function: undoButton
        function undoButtonPushed(app, event)
            undoAction(app)
            updateLabels(app)
            app.nextSegmentButton.Enable = "off";
            if app.coordinateLogIndex > 1
                app.undoButton.Enable = "on";
            else
                app.undoButton.Enable = "off";
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            saveData(app)
        end

        % Button pushed function: nextSegmentButton
        function nextSegmentButtonPushed(app, event)
            global video
            global filePath
            global matObject
            
            saveData(app)
            if app.currentAnt == app.numberOfAnts && app.currentSegment == app.numberOfSegments
                csvFileObject = readtable(fullfile(filePath, app.csvFile));
                cellArray = matObject.savedCoordinateLog(:,:);
                
                chamberArray = [];
                nestLocationArray = [];
                nestTypeArray = [];
                xCoordinates = [];
                yCoordinates = [];
                
                for k = 1:app.numberOfAnts
                    for i = 1:app.numberOfSegments
                        structObject = cellArray{k, i};
                        %chamberArray = [chamberArray; vertcat(structObject.chamber)];
                        %nestLocationArray = [nestLocationArray; vertcat(structObject.nestLocation)];
                        %nestTypeArray = [nestTypeArray; vertcat(structObject.nestType)];
                        xCoordinates = [xCoordinates; vertcat(structObject.xCoordinate)];
                        yCoordinates = [yCoordinates; vertcat(structObject.yCoordinate)];
                    end
                end
                
                %%Only for testing purposes
                xCoordinates(end) = [];
                yCoordinates(end) = [];
                
                for i = 1:length(xCoordinates)
                    if app.nestLocation == 'L'
                        if xCoordinates(i) < app.halfWayPoint
                            chamberArray(i) = "Nest";
                            nestLocationArray(i) = 'L';
                            nestTypeArray(i) = app.nestType;
                        else
                            chamberArray(i) = "Foraging Arena";
                            nestLocationArray(i) = 'L';
                            nestTypeArray(i) = app.nestType;
                        end
                    else
                        if xCoordinates(i) < app.halfWayPoint
                            chamberArray(i) = "Foraging Arena";
                            nestLocationArray(i) = 'R';
                            nestTypeArray(i) = app.nestType;
                        else
                            chamberArray(i) = "Nest";
                            nestLocationArray(i) = 'R';
                            nestTypeArray(i) = app.nestType;
                        end
                    end
                end
                
                csvFileObject.Chamber = chamberArray';
                csvFileObject.NestLocation = nestLocationArray';
                csvFileObject.NestType = nestTypeArray';
                csvFileObject.XCoord = xCoordinates;
                csvFileObject.YCoord = yCoordinates;
                
                writetable(csvFileObject, fullfile(filePath, app.csvFile))
                
            else
                updateAntAndSegment(app)

                disp(app.videoFrameIndex)
                disp(video.NumFrames)

                app.coordinateLogIndex = 1;

                app.segmentCoordinateLog = struct('xCoordinate', {}, 'yCoordinate', {});
                app.nextSegmentButton.Enable = "off";
                app.undoButton.Enable = "off";
                updateLabels(app)

                cla(app.axesView)
                formatAxesView(app)
                if app.currentSegment == 1
                    pause(3)
                end
                updateVideoFrameView(app, "up")
                saveData(app)
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global matObject
            
            app.absoluteIndex = 669;
            app.coordinateLogIndex = 85;
            app.currentSegment = 8;
            app.currentAnt = 1;
            
            for i = 1:8
                app.segmentCoordinateLog(1:app.eachSegmentLength(i)) = struct('xCoordinate', 15, 'yCoordinate', 45);
                matObject.savedCoordinateLog(1, i) = {app.segmentCoordinateLog};
            end
            
            updateLabels(app)
            updateButtons(app)
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            global matObject
            
            disp("Process started")
            
            app.absoluteIndex = 6681;
            app.coordinateLogIndex = 85;
            app.currentSegment = 8;
            app.currentAnt = 10;
            
            for j = 1:10
                for k = 1:8
                    for i = 1:84
                        app.segmentCoordinateLog(i) = struct('xCoordinate', i + k + j, 'yCoordinate', i + k + j);
                    end
                    if k < 5
                        matObject.savedCoordinateLog(j,k) = {app.segmentCoordinateLog};
                        disp(j)
                    else
                        matObject.savedCoordinateLog(j,k) = {app.segmentCoordinateLog(1:83)};
                        disp(j)
                    end
                end
            end
            
            disp("Process complete")
            
            updateLabels(app)
            updateButtons(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @figurePressed, true);
            app.axesView = uiaxes(app.UIFigure);
            app.axesView.XTick = [];
            app.axesView.YTick = [];
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Enable = 'off';
            app.saveButton.Text = 'Save';
            app.undoButton = uibutton(app.UIFigure, 'push');
            app.undoButton.ButtonPushedFcn = createCallbackFcn(app, @undoButtonPushed, true);
            app.undoButton.Enable = 'off';
            app.undoButton.Text = 'Undo';
            app.antIDLabel = uilabel(app.UIFigure);
            app.antIDLabel.Text = 'Ant ID:';
            app.nextSegmentButton = uibutton(app.UIFigure, 'push');
            app.nextSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @nextSegmentButtonPushed, true);
            app.nextSegmentButton.Enable = 'off';
            app.nextSegmentButton.Text = 'Next Segment';
            app.segmentLabel = uilabel(app.UIFigure);
            app.segmentLabel.Text = 'Segment:';
            app.indexLabel = uilabel(app.UIFigure);
            app.indexLabel.Text = 'Index:';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpatialPositionMarking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @loadView)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end